classdef SetVelocityFilteringParam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VFAmain                        matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        Usethisappto1Setcolormaplimits2SetLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        CelltrackDropDown              matlab.ui.control.DropDown
        CelltrackDropDownLabel         matlab.ui.control.Label
        MATLABfunctionsmoothdatawithgaussiansmoothingmethodLabel  matlab.ui.control.Label
        UpdateVelocityPlotButton       matlab.ui.control.Button
        CurrentFilteredVelocityMinMax  matlab.ui.control.Label
        CurrentFilteredVelocityMaxMinText  matlab.ui.control.Label
        VelocityandcolormapunitsLabel  matlab.ui.control.Label
        UnitsSwitch                    matlab.ui.control.Switch
        nPointsInput                   matlab.ui.control.NumericEditField
        nPointsinmovingaverageLabel    matlab.ui.control.Label
        SetcolormaplimitsPanel         matlab.ui.container.Panel
        LowerlimitEditField            matlab.ui.control.NumericEditField
        LowerlimitEditFieldLabel       matlab.ui.control.Label
        UpperlimitEditField            matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel       matlab.ui.control.Label
        CloseandapplyButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        TimeVector 
        TimePointData = [];
        Lapp
        VelocityFigureHandle = -1;
        AxisHandle = -1;
        Xtrack
        Ytrack
        Ztrack
    end
    
    properties (Access = public)
        %Property2 % Description
    end
    
    methods (Access = private)
        
        % function results = func(app)
        % 
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
                 
                    app.Lapp = varargin{1};
                    app.TimePointData = varargin{2};
                    app.nPointsInput.Value = varargin{5};
                    app.Lapp.DATA1 = app.nPointsInput.Value;   % Set default number of points

                    if isempty(varargin{3})
                        app.Lapp.DATA2 = [15,25];
                    else
                        app.Lapp.DATA2 = varargin{3};          % Current colormap range
                    end

                    app.Lapp.DATA3 = varargin{4};          % Current velocity units
                    
                    app.CelltrackDropDown.Items     = app.Lapp.CurrentTrackDropDown.Items;
                    app.CelltrackDropDown.ItemsData = app.Lapp.CurrentTrackDropDown.ItemsData;
                    app.CelltrackDropDown.Value     = 1;

                    nTracks = length(app.CelltrackDropDown.Items);  

                    % Preallocate space for the centroid coordinates of all tracks
                    app.Xtrack = nan( length(app.TimePointData.Measurements{1,1}.Centroid(:,1)), nTracks);
                    app.Ytrack = nan( length(app.TimePointData.Measurements{1,1}.Centroid(:,1)), nTracks);
                    app.Ztrack = nan( length(app.TimePointData.Measurements{1,1}.Centroid(:,1)), nTracks);

                    % Grab X,Y,Z points for cell tracks and applying smoothing if it's selected ..........................
                    for track = 1:nTracks
                            app.Xtrack(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,1);
                            app.Ytrack(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,2);
                            app.Ztrack(:,track) = app.TimePointData.Measurements{track,1}.Centroid(:,3);
                    end
            
                    app.SetcolormaplimitsPanel.Title = 'Set colormap limits';
                    app.UnitsSwitch.Items = [{[char(956),'m/min']},{'nm/s'}];
                    app.UnitsSwitch.ItemsData = [1,2];
                    app.UnitsSwitch.Value = app.Lapp.DATA3;
                    app.UnitsSwitch.BusyAction = 'cancel';

                    % app.VFAmain.WindowStyle = 'modal'; % 'modal' makes SelectExportFormat wait for user response

                    app.LowerlimitEditField.Value = app.Lapp.DATA2(1);
                    app.UpperlimitEditField.Value = app.Lapp.DATA2(2);
                    app.CurrentFilteredVelocityMaxMinText.Text = 'Filtered velocity [max, min]:';

                    MinV = app.Lapp.DATA2(1);
                    MaxV = app.Lapp.DATA2(2);
                    app.CurrentFilteredVelocityMinMax.Text = {['[ ',sprintf('%2.2f',MinV),', ', sprintf('%2.2f',MaxV),' ]']};

        end

        % Close request function: VFAmain
        function VFAmainCloseRequest(app, event)
            
                if isgraphics(app.VelocityFigureHandle)
                    close(app.VelocityFigureHandle)
                end

                delete(app)
            
        end

        % Button pushed function: UpdateVelocityPlotButton
        function UpdateVelocityPlotButtonPushed(app, event)
            
            
                track = app.CelltrackDropDown.Value;
                
                tStart = app.Lapp.StartingTimePoint.Value;
                tEnd   = app.Lapp.EndingTimePoint.Value;
                %tIncrement = 1; % app.LTapp.TimePointIncrement.Value;
                TimeVec = (1:length(app.TimePointData.Measurements{1,1}.Centroid(:,1)) )'; %(tStart:tIncrement:tEnd)';
                dt = app.Lapp.TimeStepField.Value; % seconds
                
                X = fillmissing( app.Xtrack(:,track),'linear','MaxGap',2);
                Y = fillmissing( app.Ytrack(:,track),'linear','MaxGap',2);
                Z = fillmissing( app.Ztrack(:,track),'linear','MaxGap',2);
                
                speed = sqrt( (X(2:end,1)-X(1:end-1,1)).^2 + (Y(2:end,1)-Y(1:end-1,1)).^2 + (Z(2:end,1)-Z(1:end-1,1)).^2  )./dt;

                Sidx = find( ~isnan(speed),1,'first');
                Eidx = find( ~isnan(speed),1,'last'); 

                if  isempty(Sidx) | isempty(Eidx)
                    speed = zeros(size(X));
                    FilteredSpeed = zeros(size(X));
                else
                    speed = interp1( TimeVec(Sidx:Eidx)+0.5, speed(Sidx:Eidx,1), TimeVec, 'linear', 'extrap'); % since we have one less velocity value than time point. 
                    nPoints = app.nPointsInput.Value;
                    app.Lapp.DATA1 = nPoints;
                    FilteredSpeed = NaN(size(speed));
                    FilteredSpeed(Sidx:Eidx,1) = smoothdata(speed(Sidx:Eidx,1),"gaussian",nPoints);
                    speed(isnan(X)) = NaN;
                    FilteredSpeed(isnan(X)) = NaN;
                end

                
                if ~isgraphics(app.VelocityFigureHandle)
                    app.VelocityFigureHandle = figure;
                    set(app.VelocityFigureHandle,'Color','w')
                    app.AxisHandle = axes('Parent',app.VelocityFigureHandle);
                end
                
                if app.UnitsSwitch.Value == 1
                    SF = 60; % Conversion factor for microns/min
                    YaxisLabel = 'Velocity (\mum/min)';
                    app.Lapp.DATA3 = 1;
                else
                    SF = 1000; % conversion factor for nm/s
                    YaxisLabel = 'Velocity (nm/s)';
                    app.Lapp.DATA3 = 2;
                end

                plot(app.AxisHandle, (TimeVec-1).*dt/60, SF*speed,         '.-','LineWidth',1,'MarkerEdgeColor','k'); 
                hold(app.AxisHandle,'on')
                plot(app.AxisHandle, (TimeVec-1).*dt/60, SF*FilteredSpeed, '-r','LineWidth',2); 
                hold(app.AxisHandle,'off')
                set(app.AxisHandle,'LineWidth',2,'FontSize',16)
                xlabel(app.AxisHandle,'Time (min)','FontSize',20)
                ylabel(app.AxisHandle,YaxisLabel,'FontSize',20)
                legend(app.AxisHandle,{'Raw velocity','Filtered velocity'},'FontSize',16,'Location','northoutside','NumColumns',2)
                xlim(app.AxisHandle,[ (TimeVec(1)-2)*dt/60, (TimeVec(end)+1)*dt/60 ])
                grid(app.AxisHandle,'on')

                % Set Colormap limits based on Filtered velocity max and min and record
                MaxV = max(SF*FilteredSpeed,[],'omitnan');
                MinV = min(SF*FilteredSpeed,[],'omitnan');
                app.CurrentFilteredVelocityMinMax.Text = {['[ ',sprintf('%2.2f',MinV),', ', sprintf('%2.2f',MaxV),' ]']};

                app.Lapp.DATA2 = [app.LowerlimitEditField.Value, app.UpperlimitEditField.Value];


        end

        % Button pushed function: CloseandapplyButton
        function CloseandapplyButtonPushed(app, event)
            
                nPoints = app.nPointsInput.Value;
                app.Lapp.DATA1 = nPoints;

                app.Lapp.DATA2 = [app.LowerlimitEditField.Value, app.UpperlimitEditField.Value];

                if app.UnitsSwitch.Value == 1
                    app.Lapp.DATA3 = 1;
                else
                    app.Lapp.DATA3 = 2;
                end


                VFAmainCloseRequest(app, event)

        end

        % Value changed function: UpperlimitEditField
        function UpperlimitEditFieldValueChanged(app, event)
                       
            app.Lapp.DATA2 = [app.LowerlimitEditField.Value, app.UpperlimitEditField.Value];

        end

        % Value changed function: LowerlimitEditField
        function LowerlimitEditFieldValueChanged(app, event)
            
            app.Lapp.DATA2 = [app.LowerlimitEditField.Value, app.UpperlimitEditField.Value];
            
        end

        % Value changed function: UnitsSwitch
        function UnitsSwitchValueChanged(app, event)
            
            value = app.UnitsSwitch.Value;
            app.Lapp.DATA3 = value;
            pause(1)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VFAmain = uifigure('Visible', 'off');
            app.VFAmain.Name = 'Velocity processing app';
            app.VFAmain.CloseRequestFcn = createCallbackFcn(app, @VFAmainCloseRequest, true);
            app.CloseandapplyButton = uibutton(app.VFAmain, 'push');
            app.CloseandapplyButton.ButtonPushedFcn = createCallbackFcn(app, @CloseandapplyButtonPushed, true);
            app.CloseandapplyButton.Text = 'Close and apply';
            app.Panel = uipanel(app.VFAmain);
            app.SetcolormaplimitsPanel = uipanel(app.Panel);
            app.SetcolormaplimitsPanel.Title = 'Set colormap limits';
            app.UpperlimitEditFieldLabel = uilabel(app.SetcolormaplimitsPanel);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit:';
            app.UpperlimitEditField = uieditfield(app.SetcolormaplimitsPanel, 'numeric');
            app.UpperlimitEditField.ValueDisplayFormat = '%.3f';
            app.UpperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperlimitEditFieldValueChanged, true);
            app.LowerlimitEditFieldLabel = uilabel(app.SetcolormaplimitsPanel);
            app.LowerlimitEditFieldLabel.Text = 'Lower limit:';
            app.LowerlimitEditField = uieditfield(app.SetcolormaplimitsPanel, 'numeric');
            app.LowerlimitEditField.ValueDisplayFormat = '%.3f';
            app.LowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerlimitEditFieldValueChanged, true);
            app.nPointsinmovingaverageLabel = uilabel(app.Panel);
            app.nPointsinmovingaverageLabel.Text = {'nPoints in gaussian '; 'moving average:'};
            app.nPointsInput = uieditfield(app.Panel, 'numeric');
            app.nPointsInput.Limits = [3 51];
            app.nPointsInput.ValueDisplayFormat = '%.0f';
            app.nPointsInput.Value = 21;
            app.UnitsSwitch = uiswitch(app.Panel, 'slider');
            app.UnitsSwitch.Items = {'um/min', 'nm/s'};
            app.UnitsSwitch.ValueChangedFcn = createCallbackFcn(app, @UnitsSwitchValueChanged, true);
            app.UnitsSwitch.Value = 'um/min';
            app.VelocityandcolormapunitsLabel = uilabel(app.Panel);
            app.VelocityandcolormapunitsLabel.Text = 'Velocity and colormap units';
            app.CurrentFilteredVelocityMaxMinText = uilabel(app.Panel);
            app.CurrentFilteredVelocityMaxMinText.Text = 'Filtered velocity [max, min]:';
            app.CurrentFilteredVelocityMinMax = uilabel(app.Panel);
            app.CurrentFilteredVelocityMinMax.Text = '[ 8.000, 15.000 ]';
            app.UpdateVelocityPlotButton = uibutton(app.Panel, 'push');
            app.UpdateVelocityPlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVelocityPlotButtonPushed, true);
            app.UpdateVelocityPlotButton.Text = 'Update sample velocity plot';
            app.MATLABfunctionsmoothdatawithgaussiansmoothingmethodLabel = uilabel(app.Panel);
            app.MATLABfunctionsmoothdatawithgaussiansmoothingmethodLabel.Text = {'MATLAB function used: smoothdata (with ''gaussian'' smoothing method).'; 'Single missing position values are linearly interpolated.'};
            app.CelltrackDropDownLabel = uilabel(app.Panel);
            app.CelltrackDropDownLabel.Text = 'Cell track:';
            app.CelltrackDropDown = uidropdown(app.Panel);
            app.CelltrackDropDown.Items = {'Track 1', 'Track 2', 'Track 3', 'Track 4'};
            app.CelltrackDropDown.ItemsData = {'1,2,3,4'};
            app.CelltrackDropDown.Value = '1,2,3,4';
            app.Panel_2 = uipanel(app.VFAmain);
            app.Usethisappto1Setcolormaplimits2SetLabel = uilabel(app.Panel_2);
            app.Usethisappto1Setcolormaplimits2SetLabel.Text = {'Use this app to:'; '        1. Set colormap/colorbar limits.'; '        2. Set velocity and colormap/colorbar units.'; '        3. Set nPoints used to smooth velocity data.'};
            % Show the figure after all components are created
            app.VFAmain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetVelocityFilteringParam(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VFAmain)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VFAmain)
        end
    end
end